classdef LungCancer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CopyrightLabel                matlab.ui.control.Label
        LungCancerTypeDetectionLabel  matlab.ui.control.Label
        PredictedLungCancerTypeLabel  matlab.ui.control.Label
        PredictLungCancerTypeButton   matlab.ui.control.Button
        Image                         matlab.ui.control.Image
    end

    
    properties (Access = public)
        LungImage % Description
    end
    
    methods (Access = public)
        
         function results = DigitClassify(app,imagePath)
             DigitImagePath = imagePath;
       
           % Load the .mat file with the model
            loadedData = load(//upload the full path of .mat file);
            
            net = loadedData.net; % Load the network from 'net'
            

            classNames = loadedData.classNames; % Use predefined class names

            
            % Define the fixed image size (based on input size)
            inputSize = net.Layers(1).InputSize(1:2); % Get network input size

            % Read and preprocess the image
            im = imread(DigitImagePath); % Read the uploaded image

           
            im = imresize(im, inputSize); % Resize image to network input size

            im = single(im); % Convert to single precision (required by many networks)

            % Classify the image
            dlIm = dlarray(im, 'SSCB'); % For dlnetwork, use dlarray
            scores = predict(net, dlIm); % Prediction for dlnetwork
           

            % Convert scores to labels
            [score, idx] = max(scores); % Get the highest score and index
            results = classNames(idx); % Map index to class name

         end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg', 'Image Files (*.jpg, *.png, *.jpeg)'});
            if isequal(file, 0)
                return; % User canceled the selection
            end
            app.LungImage = fullfile(path, file);
        end

        % Button pushed function: PredictLungCancerTypeButton
        function PredictLungCancerTypeButtonPushed(app, event)
            if isempty(app.LungImage)
                app.PredictedLungCancerTypeLabel.Text = 'Please upload digit image';
                return;
            end
        
            % Run the Digit model
            try
                app.PredictedLungCancerTypeLabel.Text = app.DigitClassify(app.LungImage);

            catch ME
                app.PredictedLungCancerTypeLabel.Text = ME.message;
                return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'LungCancerDetection.jpg';
            app.PredictLungCancerTypeButton = uibutton(app.UIFigure, 'push');
            app.PredictLungCancerTypeButton.ButtonPushedFcn = createCallbackFcn(app, @PredictLungCancerTypeButtonPushed, true);
            app.PredictLungCancerTypeButton.Text = 'Predict Lung Cancer Type';
            app.PredictedLungCancerTypeLabel = uilabel(app.UIFigure);
            app.PredictedLungCancerTypeLabel.Text = 'Predicted Lung Cancer Type';
            app.LungCancerTypeDetectionLabel = uilabel(app.UIFigure);
            app.LungCancerTypeDetectionLabel.Text = 'Lung Cancer Type Detection';
            app.CopyrightLabel = uilabel(app.UIFigure);
            app.CopyrightLabel.Text = '@2025 Copyright';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LungCancer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end